classdef Sip_projectTeam4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ReadImageButton           matlab.ui.control.Button
        SinglePlaneImageButton    matlab.ui.control.Button
        BinaryThresholdingButton  matlab.ui.control.Button
        PerfectMazecheckButton    matlab.ui.control.Button
        DilationButton            matlab.ui.control.Button
        FillingButton             matlab.ui.control.Button
        ErosionButton             matlab.ui.control.Button
        SolutionBWButton          matlab.ui.control.Button
        SolutionButton            matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadImageButton
        function ReadImageButtonPushed(app, event)
            [file,pathh] = uigetfile("*.*");
            global img;
            image = strcat(pathh,file);
            img = imread(image);
           
        end

        % Button pushed function: SinglePlaneImageButton
        function SinglePlaneImageButtonPushed(app, event)
            global img;
            global SinglePlaneimg
            global p;
            [r,c,p] = size(img);
            if p>1
                Rp = img(:,:,1);
                Bp = img(:,:,2);
                Gp = img(:,:,3);
                devRed = std(single(Rp(:)));
	            devGreen = std(single(Gp(:)));
	            devBlue = std(single(Bp(:)));
                if devRed >= devGreen && devRed >= devBlue
			        % Red has most contrast - use that channel.
		            SinglePlaneimg = single(Rp);
                elseif devGreen >=devRed && devGreen >= devBlue
                    SinglePlaneimg = single(Gp);
                else
                    SinglePlaneimg = single(Bp);
                end
            else
                SinglePlaneimg = single(img)
            cla(app.UIAxes, 'reset') 
            imshow(SinglePlaneimg,"Parent",app.UIAxes)         
            end
           
        end

        % Button pushed function: BinaryThresholdingButton
        function BinaryThresholdingButtonPushed(app, event)
            global SinglePlaneimg;
            global BiThreshImg;


            pixmax = max(max(SinglePlaneimg));
            pixmin = min(min(SinglePlaneimg)); 
%scaling image from 0-255
            SinglePlaneimg = uint8(255 * (single(SinglePlaneimg) - pixmin) / (pixmax - pixmin));
% Threshold to get the walls.  This will also sharpen up blurry, fuzzy wall edges.
            Threshhval = uint8((pixmax + pixmin) / 2);
            BiThreshImg = 255 * (SinglePlaneimg < Threshhval);

            imshow(BiThreshImg,"Parent",app.UIAxes);
        end

        % Button pushed function: PerfectMazecheckButton
        function PerfectMazecheckButtonPushed(app, event)
            global BiThreshImg;
            global BiThreshImg2;
            [IImlabbles WalllsCountt] = bwlabel(BiThreshImg, 4);     % Label each blob so we can make measurements of it
            coloredLabels = label2rgb (IImlabbles, 'hsv', 'k', 'shuffle');
            caption = sprintf('Labeled image of the %d walls, each a different color', WalllsCountt);
            title(caption, 'FontSize', 10);
            imshow(coloredLabels)
            if WalllsCountt ~= 2
                message = sprintf('Not a perfect Maze so results might be unusual', WalllsCountt);
                uiwait(msgbox(message));
            end
            BiThreshImg2 = (IImlabbles == 3);
            imshow(BiThreshImg2,"Parent",app.UIAxes)
        end

        % Button pushed function: DilationButton
        function DilationButtonPushed(app, event)
            global BiThreshImg2;
            global dilationAmount;
            global AftDilImage;
            dilationAmount = 7; 
            AftDilImage = imdilate(BiThreshImg2, ones(dilationAmount));
            imshow(AftDilImage,"Parent",app.UIAxes)

        end

        % Button pushed function: FillingButton
        function FillingButtonPushed(app, event)
            global AftDilImage;
            global AftFillImg;
            AftFillImg  = imfill(AftDilImage, 'holes');
            imshow(AftFillImg,"Parent",app.UIAxes)
        end

        % Button pushed function: ErosionButton
        function ErosionButtonPushed(app, event)
            global AftFillImg;
            global dilationAmount;
            
            global AftErosionImg;
            AftErosionImg = imerode(AftFillImg, ones(dilationAmount));
            
            %solution(AftErosionImg) = 0;
            %(AftErosionImg) = 0;
            imshow(AftErosionImg,"Parent",app.UIAxes);
        end

        % Button pushed function: SolutionBWButton
        function SolutionBWButtonPushed(app, event)
            global AftErosionImg;
            global  solution;
            global AftFillImg;
            solution = AftFillImg;
            
            solution(AftErosionImg) = 0;
            imshow(solution,"Parent",app.UIAxes)
        end

        % Button pushed function: SolutionButton
        function SolutionButtonPushed(app, event)
            global p;
            global SinglePlaneimg;
            global solution;
            if p == 1
		        redPlane = SinglePlaneimg;
		        greenPlane = SinglePlaneimg;
		        bluePlane = SinglePlaneimg;
            end
	        redPlane(solution) = 0;
	        greenPlane(solution) =255;
	        bluePlane(solution) = 0;
	        solvedImage = cat(3, redPlane, greenPlane, bluePlane);
	% Display the results of this step.
	    
	        imshow(solvedImage,"Parent",app.UIAxes);
	
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReadImageButton = uibutton(app.UIFigure, 'push');
            app.ReadImageButton.ButtonPushedFcn = createCallbackFcn(app, @ReadImageButtonPushed, true);
            app.ReadImageButton.Text = 'ReadImage';
            app.SinglePlaneImageButton = uibutton(app.UIFigure, 'push');
            app.SinglePlaneImageButton.ButtonPushedFcn = createCallbackFcn(app, @SinglePlaneImageButtonPushed, true);
            app.SinglePlaneImageButton.Text = 'SinglePlaneImage';
            app.BinaryThresholdingButton = uibutton(app.UIFigure, 'push');
            app.BinaryThresholdingButton.ButtonPushedFcn = createCallbackFcn(app, @BinaryThresholdingButtonPushed, true);
            app.BinaryThresholdingButton.Text = 'BinaryThresholding';
            app.PerfectMazecheckButton = uibutton(app.UIFigure, 'push');
            app.PerfectMazecheckButton.ButtonPushedFcn = createCallbackFcn(app, @PerfectMazecheckButtonPushed, true);
            app.PerfectMazecheckButton.Text = 'PerfectMazecheck';
            app.DilationButton = uibutton(app.UIFigure, 'push');
            app.DilationButton.ButtonPushedFcn = createCallbackFcn(app, @DilationButtonPushed, true);
            app.DilationButton.Text = 'Dilation';
            app.FillingButton = uibutton(app.UIFigure, 'push');
            app.FillingButton.ButtonPushedFcn = createCallbackFcn(app, @FillingButtonPushed, true);
            app.FillingButton.Text = 'Filling';
            app.ErosionButton = uibutton(app.UIFigure, 'push');
            app.ErosionButton.ButtonPushedFcn = createCallbackFcn(app, @ErosionButtonPushed, true);
            app.ErosionButton.Text = 'Erosion';
            app.SolutionBWButton = uibutton(app.UIFigure, 'push');
            app.SolutionBWButton.ButtonPushedFcn = createCallbackFcn(app, @SolutionBWButtonPushed, true);
            app.SolutionBWButton.Text = 'SolutionBW';
            app.SolutionButton = uibutton(app.UIFigure, 'push');
            app.SolutionButton.ButtonPushedFcn = createCallbackFcn(app, @SolutionButtonPushed, true);
            app.SolutionButton.Text = 'Solution';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {''; ''})
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sip_projectTeam4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end